classdef FuelEconomyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        axes1              matlab.ui.control.UIAxes
        uipanel1           matlab.ui.container.Panel
        yearListBox        matlab.ui.control.ListBox
        selectAllYearBtn   matlab.ui.control.Button
        uipanel3           matlab.ui.container.Panel
        cityCheckBox       matlab.ui.control.CheckBox
        highwayCheckBox    matlab.ui.control.CheckBox
        uipanel4           matlab.ui.container.Panel
        text1              matlab.ui.control.Label
        confIntervalPopup  matlab.ui.control.DropDown
        uipanel2           matlab.ui.container.Panel
        carsCheckBox       matlab.ui.control.CheckBox
        trucksCheckBox     matlab.ui.control.CheckBox
        plotBtn            matlab.ui.control.Button
        statusText         matlab.ui.control.Label
    end

    
    properties (Access = private)
        dataDir;
        selectedYears = [];
        lastData;
    end
    
    methods (Access = private)
        function updateYearList(app)
            
            files = dir(fullfile(app.dataDir, '*.xlsx'));
            if isempty(files)
                years = {};
            else
                years = {files.name};
                years = cellfun(@(x) strtok(x, 'd'), years, 'UniformOutput', false);
            end
            app.yearListBox.Items = years;
            app.yearListBox.Value = years{1};
            
            app.selectedYears = [];
        end
        
        function [cf, gof] = MPG(app, carData, CarTruck, CityHighway, CI)
                % MPG(carData, CARTRUCK, CITYHIGHWAY, CI)
                %
                %   carData      : car data (table object)
                %   CARTRUCK     : 'car' or 'truck' or 'all'
                %   CITYHIGHWAY  : 'city' or 'highway' or 'all'
                %   CI (optional): Confidence Interval (default: 95)
                
                if nargin == 3 % CI not provided
                    CI = 95;
                end
                
                %% Filter out data
                if strcmpi(CarTruck, 'all')
                    idx1 = true(size(carData.MPG));
                else
                    idx1 = carData.Car_Truck == CarTruck;
                end
                if strcmpi(CityHighway, 'all')
                    idx2 = true(size(carData.MPG));
                else
                    idx2 = carData.City_Highway == CityHighway;
                end
                idx = idx1 & idx2;
                iData = carData.RatedHP(idx);
                dData = carData.MPG(idx);
                
                %prepare curve fit data
                [xData, yData] = prepareCurveData( iData, dData );

                % Set up fittype and options.
                ft = fittype( {'1', '1/x'}, 'independent', 'x', 'dependent', 'y', 'coefficients', {'a', 'b'} );
                
                % Fit model to data.
                [cf, gof] = fit( xData, yData, ft );
                                
                
                %% Plot
                scatter(app.axes1,iData,dData,'.');
                app.axes1.NextPlot = 'add';
                
                xx = linspace(min(iData),max(iData),1000);
                plot(app.axes1,xx,cf(xx),'r','LineWidth',2);
                plot(app.axes1,xx,predint(cf,xx,CI*0.01),'Color',[0 .5 0]);
                xlabel(app.axes1,'Rated Horsepower')
                ylabel(app.axes1,'MPG')
                title(app.axes1,[CarTruck ' - ' CityHighway]);
                
                app.axes1.NextPlot = 'replace';
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FuelEconomy_App_OpeningFcn(app)
            if ~exist(fullfile(fileparts(which(mfilename)), 'dataXLS'), 'dir')
                datadir = uigetdir(pwd, 'Select Data Directory');
                if isnumeric(datadir)
                    error('User cancelled');
                end
            else
                datadir = fullfile(fileparts(which(mfilename)), 'dataXLS');
            end
            
            app.dataDir = datadir;
            updateYearList(app);
        end

        % Button pushed function: plotBtn
        function plotBtn_Callback(app, event)
            
            % Error Checking - make sure options are checked
            if (~get(app.carsCheckBox, 'Value') && ~get(app.trucksCheckBox, 'Value')) || ...
                    (~get(app.cityCheckBox, 'Value') && ~get(app.highwayCheckBox, 'Value'))
                errordlg('At least one checkbox from "Car-Truck" and "City-Highway" must be selected');
                return;
            end
            
            set(app.statusText, 'Visible', 'on'); drawnow;
            
            % Get the years selected
            yearIDX = app.yearListBox.Value;
            
            % If the selection changed, reload the data
            if isempty(app.selectedYears) || ~isequal(app.selectedYears, yearIDX)
                
                years = str2double(yearIDX);
                
                % Import data from Excel (or Database)
                data = importYearXLS(years, app.dataDir);
                
                data.Car_Truck = categorical(data.Car_Truck);
                data.City_Highway = categorical(data.City_Highway);
                
                app.lastData = data;
                app.selectedYears = yearIDX;
            else
                data = app.lastData;
            end
            
            % Get confidence interval
            CI = str2double(app.confIntervalPopup.Value);
            
            % Get filtering options for Car-Truck
            if get(app.carsCheckBox, 'Value') && get(app.trucksCheckBox, 'Value')
                CarTruck = 'all';
            elseif get(app.carsCheckBox, 'Value')
                CarTruck = 'car';
            else
                CarTruck = 'truck';
            end
            
            % Get filtering options for City-Highway
            if get(app.cityCheckBox, 'Value') && get(app.highwayCheckBox, 'Value')
                CityHighway = 'all';
            elseif get(app.cityCheckBox, 'Value')
                CityHighway = 'city';
            else
                CityHighway = 'highway';
            end
            
            % Perform regression based on filtering options and CI.
            MPG(app,data, CarTruck, CityHighway, CI);
            
            set(app.statusText, 'Visible', 'off'); drawnow;
        end

        % Button pushed function: selectAllYearBtn
        function selectAllYearBtn_Callback(app, event)
            app.yearListBox.Value = app.yearListBox.Items;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FuelEconomy_App';
            app.axes1 = uiaxes(app.figure1);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Year';
            app.yearListBox = uilistbox(app.uipanel1);
            app.yearListBox.Items = {''};
            app.yearListBox.Multiselect = 'on';
            app.yearListBox.Value = {''};
            app.selectAllYearBtn = uibutton(app.uipanel1, 'push');
            app.selectAllYearBtn.ButtonPushedFcn = createCallbackFcn(app, @selectAllYearBtn_Callback, true);
            app.selectAllYearBtn.Text = 'Select All';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'City - Highway';
            app.cityCheckBox = uicheckbox(app.uipanel3);
            app.cityCheckBox.Text = 'City';
            app.cityCheckBox.Value = true;
            app.highwayCheckBox = uicheckbox(app.uipanel3);
            app.highwayCheckBox.Text = 'Highway';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Conf. Interval';
            app.text1 = uilabel(app.uipanel4);
            app.text1.Text = '%';
            app.confIntervalPopup = uidropdown(app.uipanel4);
            app.confIntervalPopup.Items = {'80', '85', '90', '95', '98', '99'};
            app.confIntervalPopup.Value = '95';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Car - Truck';
            app.carsCheckBox = uicheckbox(app.uipanel2);
            app.carsCheckBox.Text = 'Cars';
            app.carsCheckBox.Value = true;
            app.trucksCheckBox = uicheckbox(app.uipanel2);
            app.trucksCheckBox.Text = 'Trucks';
            app.plotBtn = uibutton(app.figure1, 'push');
            app.plotBtn.ButtonPushedFcn = createCallbackFcn(app, @plotBtn_Callback, true);
            app.plotBtn.Text = 'Plot';
            app.statusText = uilabel(app.figure1);
            app.statusText.Visible = 'off';
            app.statusText.Text = 'Processing';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FuelEconomyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FuelEconomy_App_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end